classdef ModellingChemicalKinetics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimpleReactionsTab             matlab.ui.container.Tab
        RateConstantEditField          matlab.ui.control.NumericEditField
        RateConstantEditFieldLabel     matlab.ui.control.Label
        InitalValueEditField_2         matlab.ui.control.NumericEditField
        InitalValueEditField_2Label    matlab.ui.control.Label
        EquationappearshereLabel_2     matlab.ui.control.Label
        PlotButton_2                   matlab.ui.control.Button
        ReactionOrderButtonGroup       matlab.ui.container.ButtonGroup
        SecondOrderButton              matlab.ui.control.RadioButton
        FirstOrderButton               matlab.ui.control.RadioButton
        ZeroOrderButton                matlab.ui.control.RadioButton
        UIAxes_2                       matlab.ui.control.UIAxes
        ComplexReactionsTab            matlab.ui.container.Tab
        AtoBLabel                      matlab.ui.control.Label
        InitalValueEditField           matlab.ui.control.NumericEditField
        InitalValueEditFieldLabel      matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        EquationappearshereLabel       matlab.ui.control.Label
        RateConstant2EditField         matlab.ui.control.NumericEditField
        RateConstant2EditFieldLabel    matlab.ui.control.Label
        RateConstant1EditField         matlab.ui.control.NumericEditField
        RateConstant1EditFieldLabel    matlab.ui.control.Label
        ReactionTypeButtonGroup        matlab.ui.container.ButtonGroup
        ReversibleButton               matlab.ui.control.RadioButton
        SuccessiveButton               matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
        ImportReactionTab              matlab.ui.container.Tab
        RootMeanSquaredErrorEditField  matlab.ui.control.NumericEditField
        RootMeanSquaredErrorEditFieldLabel  matlab.ui.control.Label
        AdjustedR2EditField            matlab.ui.control.NumericEditField
        AdjustedR2EditFieldLabel       matlab.ui.control.Label
        R2EditField                    matlab.ui.control.NumericEditField
        R2EditFieldLabel               matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        RMSEEditField_3                matlab.ui.control.NumericEditField
        RMSEEditField_3Label           matlab.ui.control.Label
        AdjustedR2EditField_4          matlab.ui.control.NumericEditField
        AdjustedR2EditField_4Label     matlab.ui.control.Label
        R2EditField_4                  matlab.ui.control.NumericEditField
        R2EditField_4Label             matlab.ui.control.Label
        RMSEEditField_2                matlab.ui.control.NumericEditField
        RMSEEditField_2Label           matlab.ui.control.Label
        AdjustedR2EditField_3          matlab.ui.control.NumericEditField
        AdjustedR2EditField_3Label     matlab.ui.control.Label
        R2EditField_3                  matlab.ui.control.NumericEditField
        R2EditField_3Label             matlab.ui.control.Label
        RMSEEditField                  matlab.ui.control.NumericEditField
        RMSEEditFieldLabel             matlab.ui.control.Label
        AdjustedR2EditField_2          matlab.ui.control.NumericEditField
        AdjustedR2EditField_2Label     matlab.ui.control.Label
        R2EditField_2                  matlab.ui.control.NumericEditField
        R2EditField_2Label             matlab.ui.control.Label
        ViewButton_3                   matlab.ui.control.Button
        ViewButton_2                   matlab.ui.control.Button
        ViewButton                     matlab.ui.control.Button
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        TimeColumnDropDown             matlab.ui.control.DropDown
        TimeColumnDropDownLabel        matlab.ui.control.Label
        EquationLabel                  matlab.ui.control.Label
        ReactionOrderLabel             matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ImportDatasetButton            matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Ks = [];
        RMSEs = [];
        Rs = [];
        ARs = [];
        m0;
        m1;
        m2;
        reactant;
        time;

        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              %clear all;
              screenSize = get(groot,'ScreenSize');
              screenWidth = screenSize(3);
              screenHeight = screenSize(4);
              left = screenWidth*0.1;
              bottom = screenHeight*0.1;
              width = screenWidth*0.8;
              height = screenHeight*0.8;
              drawnow;
              app.UIFigure.Position = [left bottom width height]; 
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            sympref('FloatingPointOutput',true);
            syms a(t) b(t) c(t);

            a0 = app.InitalValueEditField.Value; 
            k_1 = app.RateConstant1EditField.Value;  
            k_2 = app.RateConstant2EditField.Value; 
            if app.SuccessiveButton.Value
                if (k_1 ~= k_2)
                    a(t) = a0*exp(-k_1*t);
                    b(t) = ((k_1*a0)/(k_2 - k_1))*(exp(-k_1*t) - exp(-k_2*t));
                    c(t) =  a0 - a(t) - b(t);
                else
                    a(t) = a0*exp(-k_1*t);
                    b(t) = k_1*a0*t.*exp(-k_1*t);
                    c(t) =  a0 - a(t) - b(t);
                end

                app.AtoBLabel.Text = "$\ce{ A ->[k_1] B ->[k_2] C}$";
                 
            else
                if (k_1 ~= k_2)
                    a(t) = a0 * ((k_2 + (k_1 * exp(-(k_2 +k_1) * t)))/(k_2 + k_1));
                    b(t) = a0 - a(t);
                else
                    a(t) = a0 * ((k_2 + (k_1 * exp(-(k_2 +k_1) * t)))/(k_2 + k_1));
                    b(t) = a0 - a(t);
                end
            end

            app.EquationappearshereLabel.Interpreter = 'latex';
            fplot(app.UIAxes,a(t))
            eqts(1) = append("$A(t) = ",latex(a(t))," $");
            eqts(2) = append("$B(t) = ",latex(b(t))," $");
            legends(1) = "A(t)";
            hold(app.UIAxes, 'on')
            fplot(app.UIAxes,b(t))
            legends(2) = "B(t)";
            hold(app.UIAxes, 'on')
            if app.SuccessiveButton.Value
                fplot(app.UIAxes,c(t))
                legends(3) = "C(t)";
                eqts(3) = append("$C(t) = ",latex(c(t))," $");
            end

            axis(app.UIAxes,'auto')
            %app.UIAxes.XLim(1) = 0;
            %app.UIAxes.YLim(1) = 0;
            xlabel(app.UIAxes,'Time (t)');
            ylabel(app.UIAxes, 'Concentration (Moles per Volume)');
            legend(app.UIAxes, legends);
            hold(app.UIAxes, 'off')
            
            app.EquationappearshereLabel.Text = eqts;


        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            sympref('FloatingPointOutput',true)
            syms a(t) b(t) g(t)

            a0 = app.InitalValueEditField_2.Value; 
            %t = -2:0.1:10;
            k = app.RateConstantEditField.Value;                    
            
            if app.ZeroOrderButton.Value
                a(t) = a0-k*t;
                b(t) = a0 - a(t);
                app.EquationappearshereLabel_2.Text = sprintf('A(t) = %s\nB(t) = %s',a(t),b(t));
                ttl = "Zero Order Reaction";
                
            elseif app.FirstOrderButton.Value
                a(t) = a0*exp(-k*t);
                b(t) = a0 - a(t);
                app.EquationappearshereLabel_2.Text = sprintf('A(t) = %s\nB(t) = %s',a(t),b(t));
                ttl = "First Order Reaction";
            elseif app.SecondOrderButton.Value
                g(t) = (a0^-1 + k*t);
                a(t) = 1./g(t);
                b(t) = a0 - a(t);
                app.EquationappearshereLabel_2.Text = sprintf('A(t) = %s\nB(t) = %s',a(t),b(t));
                ttl = "Second Order Reaction";

            else
            end
            
            fplot(app.UIAxes_2,a(t))
            legends(1) = "A(t)";
            hold(app.UIAxes_2, 'on')

            fplot(app.UIAxes_2,b(t))
            legends(2) = "B(t)";
            hold(app.UIAxes_2, 'on')

            axis(app.UIAxes_2,'auto')
            app.UIAxes_2.XLim(1) = 0;
            app.UIAxes_2.YLim(1) = 0;
            legend(app.UIAxes_2, legends);
            title(app.UIAxes_2, ttl)
            hold(app.UIAxes_2, 'off')

            eqts(1) = append("$A(t) = ",latex(a(t))," $");
            eqts(2) = append("$B(t) = ",latex(b(t))," $");

            app.EquationappearshereLabel_2.Interpreter = "latex";
            app.EquationappearshereLabel_2.Text = eqts;
            
        end

        % Button pushed function: ImportDatasetButton
        function ImportDatasetButtonPushed(app, event)
             filterspec = {'*.csv;*.xlsx','All Files'};
            [file, path] = uigetfile(filterspec);
            filename = [path file];
            t = readtable(filename);
            
            
            for n = 1:width(t)
                if isa(t.(n), 'cell')
                    v2 = table2array(t(:,n));
                    v2 = string(v2);
                    old = {' × 10', 'x10^',  'x10' , 'x 10 ^'};
                    new = 'e';
                    newv2 = replace(v2,old,new);
                    newv2 = double(newv2);
                    t.(n) = str2double(t{:,n}); 
                    t.(n) = newv2;
                end
               
            end
           
            app.UITable.Data = t;
            app.UITable.ColumnName = t.Properties.VariableDescriptions; 
            app.TimeColumnDropDown.Items = app.UITable.ColumnName;

            app.time = find(string(app.UITable.ColumnName) == app.TimeColumnDropDown.Value);
            nn = 1;        
            %legends = [];
           
            for n = 1:width(app.UITable.Data)
                
                if n ~= app.time
                    plot(app.UIAxes_3,app.UITable.Data.(app.time),app.UITable.Data.(n), 'o-')
                    legends(nn) = string(app.UITable.ColumnName(n));
                    nn = nn+1;
                    hold(app.UIAxes_3, 'on')

                end
            end

            xlabel(app.UIAxes_3,'Time (t)');
            ylabel(app.UIAxes_3, 'Concentration (Moles per Volume)');
            legend(app.UIAxes_3, legends);
            hold(app.UIAxes_3, 'off')

            for n = 1:width(t)
                if ((app.UITable.Data{1,n} ~= 0) && (n ~= app.time))
                    app.reactant =n;
                    break
                end
            end
            %Need to specify time variable
            app.EquationLabel.Interpreter = 'latex';
            a0 = app.UITable.Data{1,app.reactant};

            sympref('FloatingPointOutput',true)
            syms a(t) b(t)


            %zero order
            app.m0 = fitlm(app.UITable.Data.(app.time), app.UITable.Data.(app.reactant));
            model = app.m0;
            R = model.Rsquared.Ordinary;
            
            app.ReactionOrderLabel.Text = 'Zero Order Reaction';
            k = model.Coefficients.Estimate(2);
            k(1) = k;

            app.Rs(1) = R;
            app.ARs(1) = model.Rsquared.Adjusted;
            app.RMSEs(1) = model.RMSE;
            app.Ks(1) = k;
            
            a(t) = a0 - k*t;
            app.EquationLabel.Text = append("$A(t) = ",latex(a(t))," $");

            plot(app.UIAxes3,model);
            app.UIAxes3.Title.String = "Zero Order Fit";


            %First Order?
            app.m1 = fitlm(app.UITable.Data.(app.time), log(app.UITable.Data.(app.reactant)));
            m = app.m1;
            r = m.Rsquared.Ordinary;
            kk = m.Coefficients.Estimate(2);

            app.Rs(2) = r;
            app.ARs(2) = m.Rsquared.Adjusted;
            app.RMSEs(2) = m.RMSE;
            app.Ks(2) = kk;
            b(t) = a0*exp(kk*t);

            plot(app.UIAxes4,m);
            plot(app.UIAxes2,model);
            app.UIAxes4.Title.String = "First Order Fit";
            

            if ((1-abs(R))>= (1 - abs(r)))
                model = m;
                R = r;
                app.ReactionOrderLabel.Text = 'First Order Reaction';
                k = kk;
                a(t) = a0*exp(k*t);
                app.EquationLabel.Text = append("$A(t) = ",latex(a(t))," $");
                plot(app.UIAxes2,model);
            end
            
            %Second Order?
            app.m2 = fitlm(app.UITable.Data.(app.time), 1./app.UITable.Data.(app.reactant));
            m = app.m2;
            r = m.Rsquared.Ordinary;
            
            kk = m.Coefficients.Estimate(2);
            app.Rs(3) = r;
            app.ARs(3) = m.Rsquared.Adjusted;
            app.RMSEs(3) = m.RMSE;
            app.Ks(3) = kk;
            b(t) = a0*exp(kk*t);

            plot(app.UIAxes5,m);
            app.UIAxes5.Title.String = "Second Order Fit";

            if ((1-abs(R))>= (1 - abs(r)))
                model = m;
                R = r;
                app.ReactionOrderLabel.Text = 'Second Order Reaction';
                k = model.Coefficients.Estimate(2);
                a(t) = 1./(a0^-1 + k*t);
                app.EquationLabel.Text = append("$A(t) = ",latex(a(t))," $");
                plot(app.UIAxes2,model);
            
            end
            
            title(app.UIAxes2,app.ReactionOrderLabel.Text+" Fit")

            app.R2EditField_2.Value = app.Rs(1);
            app.AdjustedR2EditField_2.Value = app.ARs(1);
            app.RMSEEditField.Value = app.RMSEs(1);
            
            app.R2EditField_3.Value = app.Rs(2);
            app.AdjustedR2EditField_3.Value = app.ARs(2);
            app.RMSEEditField_2.Value = app.RMSEs(2);

            app.R2EditField_4.Value = app.Rs(3);
            app.AdjustedR2EditField_4.Value = app.ARs(3);
            app.RMSEEditField_3.Value = app.RMSEs(3);


            app.ViewButton.Enable = "on";
            app.ViewButton_2.Enable = "on";
            app.ViewButton_3.Enable = "on";
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
            model = fitlm(app.UITable.Data.(app.time), app.UITable.Data.(app.reactant));
            plot(app.UIAxes2,model)
            app.R2EditField.Value = model.Rsquared.Ordinary;
            app.AdjustedR2EditField.Value = model.Rsquared.Adjusted;
            app.RootMeanSquaredErrorEditField.Value = model.RMSE;
            app.UIAxes2.Title.String = "Zero Order Fit";
        end

        % Button pushed function: ViewButton_2
        function ViewButton_2Pushed(app, event)
            model = fitlm(app.UITable.Data.(app.time), log(app.UITable.Data.(app.reactant)));
            plot(app.UIAxes2,model)
            app.R2EditField.Value = model.Rsquared.Ordinary;
            app.AdjustedR2EditField.Value = model.Rsquared.Adjusted;
            app.RootMeanSquaredErrorEditField.Value = model.RMSE;
            app.UIAxes2.Title.String = "First Order Fit";
        end

        % Button pushed function: ViewButton_3
        function ViewButton_3Pushed(app, event)
            model = fitlm(app.UITable.Data.(app.time), 1./(app.UITable.Data.(app.reactant)));
            plot(app.UIAxes2,model)
            app.R2EditField.Value = model.Rsquared.Ordinary;
            app.AdjustedR2EditField.Value = model.Rsquared.Adjusted;
            app.RootMeanSquaredErrorEditField.Value = model.RMSE;
            app.UIAxes2.Title.String = "Second Order Fit";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimpleReactionsTab = uitab(app.TabGroup);
            app.SimpleReactionsTab.Title = 'Simple Reactions';
            app.UIAxes_2 = uiaxes(app.SimpleReactionsTab);
            title(app.UIAxes_2, 'Simple Reactions')
            xlabel(app.UIAxes_2, 'Time (t)')
            ylabel(app.UIAxes_2, 'Concentration')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 Inf];
            app.UIAxes_2.YLim = [0 Inf];
            app.ReactionOrderButtonGroup = uibuttongroup(app.SimpleReactionsTab);
            app.ReactionOrderButtonGroup.Title = 'Reaction Order';
            app.ZeroOrderButton = uiradiobutton(app.ReactionOrderButtonGroup);
            app.ZeroOrderButton.Text = 'Zero Order';
            app.ZeroOrderButton.Value = true;
            app.FirstOrderButton = uiradiobutton(app.ReactionOrderButtonGroup);
            app.FirstOrderButton.Text = 'First Order';
            app.SecondOrderButton = uiradiobutton(app.ReactionOrderButtonGroup);
            app.SecondOrderButton.Text = 'Second Order';
            app.PlotButton_2 = uibutton(app.SimpleReactionsTab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.EquationappearshereLabel_2 = uilabel(app.SimpleReactionsTab);
            app.EquationappearshereLabel_2.Text = {'Equation appears here....'; ''};
            app.InitalValueEditField_2Label = uilabel(app.SimpleReactionsTab);
            app.InitalValueEditField_2Label.Text = {'Inital Value'; ''};
            app.InitalValueEditField_2 = uieditfield(app.SimpleReactionsTab, 'numeric');
            app.InitalValueEditField_2.Value = 1;
            app.RateConstantEditFieldLabel = uilabel(app.SimpleReactionsTab);
            app.RateConstantEditFieldLabel.Text = 'Rate Constant';
            app.RateConstantEditField = uieditfield(app.SimpleReactionsTab, 'numeric');
            app.RateConstantEditField.Value = 1;
            app.ComplexReactionsTab = uitab(app.TabGroup);
            app.ComplexReactionsTab.Title = 'Complex Reactions';
            app.UIAxes = uiaxes(app.ComplexReactionsTab);
            title(app.UIAxes, 'Complex Reaction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.YLimitMethod = 'tight';
            app.ReactionTypeButtonGroup = uibuttongroup(app.ComplexReactionsTab);
            app.ReactionTypeButtonGroup.Title = 'Reaction Type';
            app.SuccessiveButton = uiradiobutton(app.ReactionTypeButtonGroup);
            app.SuccessiveButton.Text = 'Successive';
            app.SuccessiveButton.Value = true;
            app.ReversibleButton = uiradiobutton(app.ReactionTypeButtonGroup);
            app.ReversibleButton.Text = 'Reversible';
            app.RateConstant1EditFieldLabel = uilabel(app.ComplexReactionsTab);
            app.RateConstant1EditFieldLabel.Text = 'Rate Constant 1';
            app.RateConstant1EditField = uieditfield(app.ComplexReactionsTab, 'numeric');
            app.RateConstant1EditField.Value = 1;
            app.RateConstant2EditFieldLabel = uilabel(app.ComplexReactionsTab);
            app.RateConstant2EditFieldLabel.Text = 'Rate Constant 2';
            app.RateConstant2EditField = uieditfield(app.ComplexReactionsTab, 'numeric');
            app.RateConstant2EditField.Value = 1;
            app.EquationappearshereLabel = uilabel(app.ComplexReactionsTab);
            app.EquationappearshereLabel.Text = {'Equation appears here....'; ''};
            app.PlotButton = uibutton(app.ComplexReactionsTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.InitalValueEditFieldLabel = uilabel(app.ComplexReactionsTab);
            app.InitalValueEditFieldLabel.Text = {'Inital Value'; ''};
            app.InitalValueEditField = uieditfield(app.ComplexReactionsTab, 'numeric');
            app.InitalValueEditField.Value = 1;
            app.AtoBLabel = uilabel(app.ComplexReactionsTab);
            app.AtoBLabel.Interpreter = 'latex';
            app.AtoBLabel.Text = '$A \to B$';
            app.ImportReactionTab = uitab(app.TabGroup);
            app.ImportReactionTab.Title = 'Import Reaction';
            app.UIAxes_3 = uiaxes(app.ImportReactionTab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2 = uiaxes(app.ImportReactionTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ImportDatasetButton = uibutton(app.ImportReactionTab, 'push');
            app.ImportDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDatasetButtonPushed, true);
            app.ImportDatasetButton.Text = 'Import Dataset';
            app.UITable = uitable(app.ImportReactionTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.ReactionOrderLabel = uilabel(app.ImportReactionTab);
            app.ReactionOrderLabel.Text = 'Reaction Order';
            app.EquationLabel = uilabel(app.ImportReactionTab);
            app.EquationLabel.Text = 'Equation';
            app.TimeColumnDropDownLabel = uilabel(app.ImportReactionTab);
            app.TimeColumnDropDownLabel.Text = 'Time Column';
            app.TimeColumnDropDown = uidropdown(app.ImportReactionTab);
            app.Panel = uipanel(app.ImportReactionTab);
            app.Panel.Title = 'Panel';
            app.UIAxes3 = uiaxes(app.Panel);
            title(app.UIAxes3, 'Zero Order Fit')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.Panel);
            title(app.UIAxes4, 'First Order Fit')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.Panel);
            title(app.UIAxes5, 'Second Order Fit')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.ViewButton = uibutton(app.Panel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Enable = 'off';
            app.ViewButton.Text = 'View';
            app.ViewButton_2 = uibutton(app.Panel, 'push');
            app.ViewButton_2.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_2Pushed, true);
            app.ViewButton_2.Enable = 'off';
            app.ViewButton_2.Text = 'View';
            app.ViewButton_3 = uibutton(app.Panel, 'push');
            app.ViewButton_3.ButtonPushedFcn = createCallbackFcn(app, @ViewButton_3Pushed, true);
            app.ViewButton_3.Enable = 'off';
            app.ViewButton_3.Text = 'View';
            app.R2EditField_2Label = uilabel(app.Panel);
            app.R2EditField_2Label.Interpreter = 'latex';
            app.R2EditField_2Label.Text = '$R^2$';
            app.R2EditField_2 = uieditfield(app.Panel, 'numeric');
            app.R2EditField_2.Editable = 'off';
            app.AdjustedR2EditField_2Label = uilabel(app.Panel);
            app.AdjustedR2EditField_2Label.Interpreter = 'latex';
            app.AdjustedR2EditField_2Label.Text = 'Adjusted $R^2$';
            app.AdjustedR2EditField_2 = uieditfield(app.Panel, 'numeric');
            app.AdjustedR2EditField_2.Editable = 'off';
            app.RMSEEditFieldLabel = uilabel(app.Panel);
            app.RMSEEditFieldLabel.Interpreter = 'latex';
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.Panel, 'numeric');
            app.RMSEEditField.Editable = 'off';
            app.R2EditField_3Label = uilabel(app.Panel);
            app.R2EditField_3Label.Interpreter = 'latex';
            app.R2EditField_3Label.Text = '$R^2$';
            app.R2EditField_3 = uieditfield(app.Panel, 'numeric');
            app.R2EditField_3.Editable = 'off';
            app.AdjustedR2EditField_3Label = uilabel(app.Panel);
            app.AdjustedR2EditField_3Label.Interpreter = 'latex';
            app.AdjustedR2EditField_3Label.Text = 'Adjusted $R^2$';
            app.AdjustedR2EditField_3 = uieditfield(app.Panel, 'numeric');
            app.AdjustedR2EditField_3.Editable = 'off';
            app.RMSEEditField_2Label = uilabel(app.Panel);
            app.RMSEEditField_2Label.Interpreter = 'latex';
            app.RMSEEditField_2Label.Text = 'RMSE';
            app.RMSEEditField_2 = uieditfield(app.Panel, 'numeric');
            app.RMSEEditField_2.Editable = 'off';
            app.R2EditField_4Label = uilabel(app.Panel);
            app.R2EditField_4Label.Interpreter = 'latex';
            app.R2EditField_4Label.Text = '$R^2$';
            app.R2EditField_4 = uieditfield(app.Panel, 'numeric');
            app.R2EditField_4.Editable = 'off';
            app.AdjustedR2EditField_4Label = uilabel(app.Panel);
            app.AdjustedR2EditField_4Label.Interpreter = 'latex';
            app.AdjustedR2EditField_4Label.Text = 'Adjusted $R^2$';
            app.AdjustedR2EditField_4 = uieditfield(app.Panel, 'numeric');
            app.AdjustedR2EditField_4.Editable = 'off';
            app.RMSEEditField_3Label = uilabel(app.Panel);
            app.RMSEEditField_3Label.Interpreter = 'latex';
            app.RMSEEditField_3Label.Text = 'RMSE';
            app.RMSEEditField_3 = uieditfield(app.Panel, 'numeric');
            app.RMSEEditField_3.Editable = 'off';
            app.R2EditFieldLabel = uilabel(app.ImportReactionTab);
            app.R2EditFieldLabel.Interpreter = 'latex';
            app.R2EditFieldLabel.Text = '$R^2$';
            app.R2EditField = uieditfield(app.ImportReactionTab, 'numeric');
            app.R2EditField.Editable = 'off';
            app.AdjustedR2EditFieldLabel = uilabel(app.ImportReactionTab);
            app.AdjustedR2EditFieldLabel.Interpreter = 'latex';
            app.AdjustedR2EditFieldLabel.Text = 'Adjusted $R^2$';
            app.AdjustedR2EditField = uieditfield(app.ImportReactionTab, 'numeric');
            app.AdjustedR2EditField.Editable = 'off';
            app.RootMeanSquaredErrorEditFieldLabel = uilabel(app.ImportReactionTab);
            app.RootMeanSquaredErrorEditFieldLabel.Text = 'Root Mean Squared Error';
            app.RootMeanSquaredErrorEditField = uieditfield(app.ImportReactionTab, 'numeric');
            app.RootMeanSquaredErrorEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModellingChemicalKinetics

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end